classdef chooseMvt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        bodypartsLabel                 matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        headfaceTab                    matlab.ui.container.Tab
        vibrissaeCheckBox              matlab.ui.control.CheckBox
        eyeCheckBox                    matlab.ui.control.CheckBox
        jawCheckBox                    matlab.ui.control.CheckBox
        tongueCheckBox                 matlab.ui.control.CheckBox
        mystacialpadCheckBox           matlab.ui.control.CheckBox
        lipCheckBox                    matlab.ui.control.CheckBox
        earCheckBox                    matlab.ui.control.CheckBox
        forelimbTab                    matlab.ui.container.Tab
        shoulderCheckBox               matlab.ui.control.CheckBox
        elbowCheckBox                  matlab.ui.control.CheckBox
        forearmCheckBox                matlab.ui.control.CheckBox
        wristCheckBox                  matlab.ui.control.CheckBox
        digitsCheckBox                 matlab.ui.control.CheckBox
        trunkTab                       matlab.ui.container.Tab
        onlychoosemovementfortrunkLabel  matlab.ui.control.Label
        donotchoosesidefortrunkLabel   matlab.ui.control.Label
        hindlimbTab_2                  matlab.ui.container.Tab
        hipCheckBox                    matlab.ui.control.CheckBox
        kneeCheckBox                   matlab.ui.control.CheckBox
        ankleCheckBox                  matlab.ui.control.CheckBox
        toesCheckBox                   matlab.ui.control.CheckBox
        tailTab                        matlab.ui.container.Tab
        tailCheckBox                   matlab.ui.control.CheckBox
        sideLabel                      matlab.ui.control.Label
        ContralateralCheckBox          matlab.ui.control.CheckBox
        IpsilateralCheckBox            matlab.ui.control.CheckBox
        movementLabel                  matlab.ui.control.Label
        TabGroup2                      matlab.ui.container.TabGroup
        headandfaceTab                 matlab.ui.container.Tab
        protractionCheckBox            matlab.ui.control.CheckBox
        retractionCheckBox             matlab.ui.control.CheckBox
        vibrationCheckBox              matlab.ui.control.CheckBox
        movementCheckBox               matlab.ui.control.CheckBox
        lidmovementCheckBox            matlab.ui.control.CheckBox
        openingCheckBox                matlab.ui.control.CheckBox
        closingCheckBox                matlab.ui.control.CheckBox
        upperCheckBox                  matlab.ui.control.CheckBox
        lowerCheckBox                  matlab.ui.control.CheckBox
        forelimbTab_2                  matlab.ui.container.Tab
        flexionCheckBox                matlab.ui.control.CheckBox
        extensionCheckBox              matlab.ui.control.CheckBox
        medialrotationCheckBox         matlab.ui.control.CheckBox
        lateralrotationCheckBox        matlab.ui.control.CheckBox
        protractionCheckBox_2          matlab.ui.control.CheckBox
        retractionCheckBox_2           matlab.ui.control.CheckBox
        abductionCheckBox              matlab.ui.control.CheckBox
        adductionCheckBox              matlab.ui.control.CheckBox
        elevationCheckBox              matlab.ui.control.CheckBox
        depressionCheckBox             matlab.ui.control.CheckBox
        pronationCheckBox              matlab.ui.control.CheckBox
        supinationCheckBox             matlab.ui.control.CheckBox
        trunkTab_2                     matlab.ui.container.Tab
        clockwiserotationCheckBox      matlab.ui.control.CheckBox
        counterclockwiserotationCheckBox  matlab.ui.control.CheckBox
        skinhairmovementCheckBox       matlab.ui.control.CheckBox
        flexionCheckBox_2              matlab.ui.control.CheckBox
        extensionCheckBox_2            matlab.ui.control.CheckBox
        lateralflexiontoipsilateralsideCheckBox  matlab.ui.control.CheckBox
        lateralflexiontocontralateralsideCheckBox  matlab.ui.control.CheckBox
        hindlimbTab                    matlab.ui.container.Tab
        flexionCheckBox_3              matlab.ui.control.CheckBox
        extensionCheckBox_3            matlab.ui.control.CheckBox
        medialrotationCheckBox_2       matlab.ui.control.CheckBox
        lateralrotationCheckBox_2      matlab.ui.control.CheckBox
        abductionCheckBox_2            matlab.ui.control.CheckBox
        adductionCheckBox_2            matlab.ui.control.CheckBox
        pronationCheckBox_2            matlab.ui.control.CheckBox
        supinationCheckBox_2           matlab.ui.control.CheckBox
        inversionCheckBox              matlab.ui.control.CheckBox
        eversionCheckBox               matlab.ui.control.CheckBox
        tailTab_2                      matlab.ui.container.Tab
        checkanymovementcheckboxLabel  matlab.ui.control.Label
        startButton                    matlab.ui.control.Button
        addonemoreitemButton           matlab.ui.control.Button
        doneButton                     matlab.ui.control.Button
        anymovementCheckBox            matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        dataTable = readtable("dataTable.xlsx");
        newMatrix = zeros(1);
        keywords = '';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            app.keywords = '';
        end

        % Value changed function: vibrissaeCheckBox
        function vibrissaeCheckBoxValueChanged(app, event)
            value = app.vibrissaeCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'vibrissae');
            end
        end

        % Value changed function: eyeCheckBox
        function eyeCheckBoxValueChanged(app, event)
            value = app.eyeCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'eye');
            end
        end

        % Value changed function: lipCheckBox
        function lipCheckBoxValueChanged(app, event)
            value = app.lipCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'lip');
            end
        end

        % Value changed function: jawCheckBox
        function jawCheckBoxValueChanged(app, event)
            value = app.jawCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'jaw');
            end
        end

        % Value changed function: tongueCheckBox
        function tongueCheckBoxValueChanged(app, event)
            value = app.tongueCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'tongue');
            end
        end

        % Value changed function: earCheckBox
        function earCheckBoxValueChanged(app, event)
            value = app.earCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'ear');
            end
        end

        % Value changed function: mystacialpadCheckBox
        function mystacialpadCheckBoxValueChanged(app, event)
            value = app.mystacialpadCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'mystacialPad');
            end
        end

        % Value changed function: ContralateralCheckBox
        function ContralateralCheckBoxValueChanged(app, event)
            value = app.ContralateralCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Contralateral');
            end
        end

        % Value changed function: IpsilateralCheckBox
        function IpsilateralCheckBoxValueChanged(app, event)
            value = app.IpsilateralCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Ipsilateral');
            end
        end

        % Value changed function: anymovementCheckBox
        function anymovementCheckBoxValueChanged(app, event)
            value = app.anymovementCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , '*');
            end
        end

        % Value changed function: protractionCheckBox
        function protractionCheckBoxValueChanged(app, event)
            value = app.protractionCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Protraction');
            end
        end

        % Value changed function: retractionCheckBox
        function retractionCheckBoxValueChanged(app, event)
            value = app.retractionCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Retraction');
            end
        end

        % Value changed function: vibrationCheckBox
        function vibrationCheckBoxValueChanged(app, event)
            value = app.vibrationCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Vibration');
            end
        end

        % Value changed function: movementCheckBox
        function movementCheckBoxValueChanged(app, event)
            value = app.movementCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Movement');
            end
        end

        % Value changed function: lidmovementCheckBox
        function lidmovementCheckBoxValueChanged(app, event)
            value = app.lidmovementCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'LidMovement');
            end
        end

        % Value changed function: openingCheckBox
        function openingCheckBoxValueChanged(app, event)
            value = app.openingCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Opening');
            end
        end

        % Value changed function: closingCheckBox
        function closingCheckBoxValueChanged(app, event)
            value = app.closingCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Closing');
            end
        end

        % Value changed function: upperCheckBox
        function upperCheckBoxValueChanged(app, event)
            value = app.upperCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Upper');
            end
        end

        % Value changed function: lowerCheckBox
        function lowerCheckBoxValueChanged(app, event)
            value = app.lowerCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Lower');
            end
        end

        % Value changed function: shoulderCheckBox
        function shoulderCheckBoxValueChanged(app, event)
            value = app.shoulderCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'shoulder');
            end
        end

        % Value changed function: elbowCheckBox
        function elbowCheckBoxValueChanged(app, event)
            value = app.elbowCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'elbow');
            end
        end

        % Value changed function: forearmCheckBox
        function forearmCheckBoxValueChanged(app, event)
            value = app.forearmCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'forearm');
            end
        end

        % Value changed function: wristCheckBox
        function wristCheckBoxValueChanged(app, event)
            value = app.wristCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'wrist');
            end
        end

        % Value changed function: digitsCheckBox
        function digitsCheckBoxValueChanged(app, event)
            value = app.digitsCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'digits');
            end
        end

        % Value changed function: flexionCheckBox
        function flexionCheckBoxValueChanged(app, event)
            value = app.flexionCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Flexion');
            end
        end

        % Value changed function: extensionCheckBox
        function extensionCheckBoxValueChanged(app, event)
            value = app.extensionCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Extension');
            end
        end

        % Value changed function: medialrotationCheckBox
        function medialrotationCheckBoxValueChanged(app, event)
            value = app.medialrotationCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'MedialRotation');
            end
        end

        % Value changed function: lateralrotationCheckBox
        function lateralrotationCheckBoxValueChanged(app, event)
            value = app.lateralrotationCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'LateralRotation');
            end
        end

        % Value changed function: protractionCheckBox_2
        function protractionCheckBox_2ValueChanged(app, event)
            value = app.protractionCheckBox_2.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Protraction');
            end
        end

        % Value changed function: retractionCheckBox_2
        function retractionCheckBox_2ValueChanged(app, event)
            value = app.retractionCheckBox_2.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Retraction');
            end
        end

        % Value changed function: abductionCheckBox
        function abductionCheckBoxValueChanged(app, event)
            value = app.abductionCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Abduction');
            end
        end

        % Value changed function: adductionCheckBox
        function adductionCheckBoxValueChanged(app, event)
            value = app.adductionCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Adduction');
            end
        end

        % Value changed function: elevationCheckBox
        function elevationCheckBoxValueChanged(app, event)
            value = app.elevationCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Elevation');
            end
        end

        % Value changed function: depressionCheckBox
        function depressionCheckBoxValueChanged(app, event)
            value = app.depressionCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Depression');
            end
        end

        % Value changed function: pronationCheckBox
        function pronationCheckBoxValueChanged(app, event)
            value = app.pronationCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Pronation');
            end
        end

        % Value changed function: supinationCheckBox
        function supinationCheckBoxValueChanged(app, event)
            value = app.supinationCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Supination');
            end
        end

        % Value changed function: clockwiserotationCheckBox
        function clockwiserotationCheckBoxValueChanged(app, event)
            value = app.clockwiserotationCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'trunkClockwiseRotation');
            end
        end

        % Value changed function: counterclockwiserotationCheckBox
        function counterclockwiserotationCheckBoxValueChanged(app, event)
            value = app.counterclockwiserotationCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'trunkCounterClockwiseRotation');
            end
        end

        % Value changed function: skinhairmovementCheckBox
        function skinhairmovementCheckBoxValueChanged(app, event)
            value = app.skinhairmovementCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'trunkSkinHairMovement');
            end
        end

        % Value changed function: flexionCheckBox_2
        function flexionCheckBox_2ValueChanged(app, event)
            value = app.flexionCheckBox_2.Value;
            if value == 1
                app.keywords = append (app.keywords , 'trunkFlexion');
            end
        end

        % Value changed function: extensionCheckBox_2
        function extensionCheckBox_2ValueChanged(app, event)
            value = app.extensionCheckBox_2.Value;
            if value == 1
                app.keywords = append (app.keywords , 'trunkExtension');
            end
        end

        % Value changed function: 
        % lateralflexiontocontralateralsideCheckBox
        function lateralflexiontocontralateralsideCheckBoxValueChanged(app, event)
            value = app.lateralflexiontocontralateralsideCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'trunkLateralFlexionToContralateral');
            end
        end

        % Value changed function: 
        % lateralflexiontoipsilateralsideCheckBox
        function lateralflexiontoipsilateralsideCheckBoxValueChanged(app, event)
            value = app.lateralflexiontoipsilateralsideCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'trunkLateralFlexionToIpsilateral');
            end
        end

        % Value changed function: hipCheckBox
        function hipCheckBoxValueChanged(app, event)
            value = app.hipCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'hip');
            end
        end

        % Value changed function: kneeCheckBox
        function kneeCheckBoxValueChanged(app, event)
            value = app.kneeCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'knee');
            end
        end

        % Value changed function: ankleCheckBox
        function ankleCheckBoxValueChanged(app, event)
            value = app.ankleCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'ankle');
            end
        end

        % Value changed function: toesCheckBox
        function toesCheckBoxValueChanged(app, event)
            value = app.toesCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'toes');
            end
        end

        % Value changed function: flexionCheckBox_3
        function flexionCheckBox_3ValueChanged(app, event)
            value = app.flexionCheckBox_3.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Flexion');
            end
        end

        % Value changed function: extensionCheckBox_3
        function extensionCheckBox_3ValueChanged(app, event)
            value = app.extensionCheckBox_3.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Extension');
            end
        end

        % Value changed function: medialrotationCheckBox_2
        function medialrotationCheckBox_2ValueChanged(app, event)
            value = app.medialrotationCheckBox_2.Value;
            if value == 1
                app.keywords = append (app.keywords , 'MedialRotation');
            end
        end

        % Value changed function: lateralrotationCheckBox_2
        function lateralrotationCheckBox_2ValueChanged(app, event)
            value = app.lateralrotationCheckBox_2.Value;
            if value == 1
                app.keywords = append (app.keywords , 'LateralRotation');
            end
        end

        % Value changed function: abductionCheckBox_2
        function abductionCheckBox_2ValueChanged(app, event)
            value = app.abductionCheckBox_2.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Abduction');
            end
        end

        % Value changed function: adductionCheckBox_2
        function adductionCheckBox_2ValueChanged(app, event)
            value = app.adductionCheckBox_2.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Adduction');
            end
        end

        % Value changed function: pronationCheckBox_2
        function pronationCheckBox_2ValueChanged(app, event)
            value = app.pronationCheckBox_2.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Pronation');
            end
        end

        % Value changed function: supinationCheckBox_2
        function supinationCheckBox_2ValueChanged(app, event)
            value = app.supinationCheckBox_2.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Supination');
            end
        end

        % Value changed function: inversionCheckBox
        function inversionCheckBoxValueChanged(app, event)
            value = app.inversionCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Inversion');
            end
        end

        % Value changed function: eversionCheckBox
        function eversionCheckBoxValueChanged(app, event)
            value = app.eversionCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'Eversion');
            end
        end

        % Value changed function: tailCheckBox
        function tailCheckBoxValueChanged(app, event)
            value = app.tailCheckBox.Value;
            if value == 1
                app.keywords = append (app.keywords , 'tail');
            end
        end

        % Button pushed function: addonemoreitemButton
        function addonemoreitemButtonPushed(app, event)
            fieldNames = fieldnames(app);
            allCheckBoxNamesList = fieldNames(contains(fieldNames,'CheckBox','IgnoreCase',false));
            for i = 1:numel(allCheckBoxNamesList)
                app.(allCheckBoxNamesList{i}).Value = false;
            end
            
            for j = 1:size(app.dataTable, 2)
                if isempty(regexp(app.dataTable.Properties.VariableNames{j}, app.keywords)) == 0
                    disp(j)
                    for i = 1:size(app.dataTable, 1)
                        if app.dataTable.(j)(i) == 1
                            app.newMatrix(end+1, :) = i;
                        end
                    end
                end
            end
            app.keywords = '';
            
        end

        % Button pushed function: doneButton
        function doneButtonPushed(app, event)
            app.newMatrix = unique(app.newMatrix);
            if size(app.newMatrix,1)>1
                app.newMatrix = app.newMatrix(2:end);
            end
            writematrix(app.newMatrix, "siteMvt.xlsx");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.bodypartsLabel = uilabel(app.UIFigure);
            app.bodypartsLabel.Text = 'body parts';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.headfaceTab = uitab(app.TabGroup);
            app.headfaceTab.Title = 'head & face';
            app.vibrissaeCheckBox = uicheckbox(app.headfaceTab);
            app.vibrissaeCheckBox.ValueChangedFcn = createCallbackFcn(app, @vibrissaeCheckBoxValueChanged, true);
            app.vibrissaeCheckBox.Text = 'vibrissae';
            app.eyeCheckBox = uicheckbox(app.headfaceTab);
            app.eyeCheckBox.ValueChangedFcn = createCallbackFcn(app, @eyeCheckBoxValueChanged, true);
            app.eyeCheckBox.Text = 'eye';
            app.jawCheckBox = uicheckbox(app.headfaceTab);
            app.jawCheckBox.ValueChangedFcn = createCallbackFcn(app, @jawCheckBoxValueChanged, true);
            app.jawCheckBox.Text = 'jaw';
            app.tongueCheckBox = uicheckbox(app.headfaceTab);
            app.tongueCheckBox.ValueChangedFcn = createCallbackFcn(app, @tongueCheckBoxValueChanged, true);
            app.tongueCheckBox.Text = 'tongue';
            app.mystacialpadCheckBox = uicheckbox(app.headfaceTab);
            app.mystacialpadCheckBox.ValueChangedFcn = createCallbackFcn(app, @mystacialpadCheckBoxValueChanged, true);
            app.mystacialpadCheckBox.Text = 'mystacial pad';
            app.lipCheckBox = uicheckbox(app.headfaceTab);
            app.lipCheckBox.ValueChangedFcn = createCallbackFcn(app, @lipCheckBoxValueChanged, true);
            app.lipCheckBox.Text = 'lip';
            app.earCheckBox = uicheckbox(app.headfaceTab);
            app.earCheckBox.ValueChangedFcn = createCallbackFcn(app, @earCheckBoxValueChanged, true);
            app.earCheckBox.Text = 'ear';
            app.forelimbTab = uitab(app.TabGroup);
            app.forelimbTab.Title = 'forelimb';
            app.shoulderCheckBox = uicheckbox(app.forelimbTab);
            app.shoulderCheckBox.ValueChangedFcn = createCallbackFcn(app, @shoulderCheckBoxValueChanged, true);
            app.shoulderCheckBox.Text = 'shoulder';
            app.elbowCheckBox = uicheckbox(app.forelimbTab);
            app.elbowCheckBox.ValueChangedFcn = createCallbackFcn(app, @elbowCheckBoxValueChanged, true);
            app.elbowCheckBox.Text = 'elbow';
            app.forearmCheckBox = uicheckbox(app.forelimbTab);
            app.forearmCheckBox.ValueChangedFcn = createCallbackFcn(app, @forearmCheckBoxValueChanged, true);
            app.forearmCheckBox.Text = 'forearm';
            app.wristCheckBox = uicheckbox(app.forelimbTab);
            app.wristCheckBox.ValueChangedFcn = createCallbackFcn(app, @wristCheckBoxValueChanged, true);
            app.wristCheckBox.Text = 'wrist';
            app.digitsCheckBox = uicheckbox(app.forelimbTab);
            app.digitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @digitsCheckBoxValueChanged, true);
            app.digitsCheckBox.Text = 'digits';
            app.trunkTab = uitab(app.TabGroup);
            app.trunkTab.Title = 'trunk';
            app.onlychoosemovementfortrunkLabel = uilabel(app.trunkTab);
            app.onlychoosemovementfortrunkLabel.Text = 'only choose movement for trunk';
            app.donotchoosesidefortrunkLabel = uilabel(app.trunkTab);
            app.donotchoosesidefortrunkLabel.Text = 'do not choose side for trunk';
            app.hindlimbTab_2 = uitab(app.TabGroup);
            app.hindlimbTab_2.Title = 'hindlimb';
            app.hipCheckBox = uicheckbox(app.hindlimbTab_2);
            app.hipCheckBox.ValueChangedFcn = createCallbackFcn(app, @hipCheckBoxValueChanged, true);
            app.hipCheckBox.Text = 'hip';
            app.kneeCheckBox = uicheckbox(app.hindlimbTab_2);
            app.kneeCheckBox.ValueChangedFcn = createCallbackFcn(app, @kneeCheckBoxValueChanged, true);
            app.kneeCheckBox.Text = 'knee';
            app.ankleCheckBox = uicheckbox(app.hindlimbTab_2);
            app.ankleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ankleCheckBoxValueChanged, true);
            app.ankleCheckBox.Text = 'ankle';
            app.toesCheckBox = uicheckbox(app.hindlimbTab_2);
            app.toesCheckBox.ValueChangedFcn = createCallbackFcn(app, @toesCheckBoxValueChanged, true);
            app.toesCheckBox.Text = 'toes';
            app.tailTab = uitab(app.TabGroup);
            app.tailTab.Title = 'tail';
            app.tailCheckBox = uicheckbox(app.tailTab);
            app.tailCheckBox.ValueChangedFcn = createCallbackFcn(app, @tailCheckBoxValueChanged, true);
            app.tailCheckBox.Text = 'tail';
            app.sideLabel = uilabel(app.UIFigure);
            app.sideLabel.Text = 'side';
            app.ContralateralCheckBox = uicheckbox(app.UIFigure);
            app.ContralateralCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContralateralCheckBoxValueChanged, true);
            app.ContralateralCheckBox.Text = 'Contralateral';
            app.IpsilateralCheckBox = uicheckbox(app.UIFigure);
            app.IpsilateralCheckBox.ValueChangedFcn = createCallbackFcn(app, @IpsilateralCheckBoxValueChanged, true);
            app.IpsilateralCheckBox.Text = 'Ipsilateral';
            app.movementLabel = uilabel(app.UIFigure);
            app.movementLabel.Text = 'movement';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.headandfaceTab = uitab(app.TabGroup2);
            app.headandfaceTab.Title = 'head and face';
            app.protractionCheckBox = uicheckbox(app.headandfaceTab);
            app.protractionCheckBox.ValueChangedFcn = createCallbackFcn(app, @protractionCheckBoxValueChanged, true);
            app.protractionCheckBox.Text = 'protraction';
            app.retractionCheckBox = uicheckbox(app.headandfaceTab);
            app.retractionCheckBox.ValueChangedFcn = createCallbackFcn(app, @retractionCheckBoxValueChanged, true);
            app.retractionCheckBox.Text = 'retraction';
            app.vibrationCheckBox = uicheckbox(app.headandfaceTab);
            app.vibrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @vibrationCheckBoxValueChanged, true);
            app.vibrationCheckBox.Text = 'vibration';
            app.movementCheckBox = uicheckbox(app.headandfaceTab);
            app.movementCheckBox.ValueChangedFcn = createCallbackFcn(app, @movementCheckBoxValueChanged, true);
            app.movementCheckBox.Text = 'movement';
            app.lidmovementCheckBox = uicheckbox(app.headandfaceTab);
            app.lidmovementCheckBox.ValueChangedFcn = createCallbackFcn(app, @lidmovementCheckBoxValueChanged, true);
            app.lidmovementCheckBox.Text = 'lid movement';
            app.openingCheckBox = uicheckbox(app.headandfaceTab);
            app.openingCheckBox.ValueChangedFcn = createCallbackFcn(app, @openingCheckBoxValueChanged, true);
            app.openingCheckBox.Text = 'opening';
            app.closingCheckBox = uicheckbox(app.headandfaceTab);
            app.closingCheckBox.ValueChangedFcn = createCallbackFcn(app, @closingCheckBoxValueChanged, true);
            app.closingCheckBox.Text = 'closing';
            app.upperCheckBox = uicheckbox(app.headandfaceTab);
            app.upperCheckBox.ValueChangedFcn = createCallbackFcn(app, @upperCheckBoxValueChanged, true);
            app.upperCheckBox.Text = 'upper';
            app.lowerCheckBox = uicheckbox(app.headandfaceTab);
            app.lowerCheckBox.ValueChangedFcn = createCallbackFcn(app, @lowerCheckBoxValueChanged, true);
            app.lowerCheckBox.Text = 'lower';
            app.forelimbTab_2 = uitab(app.TabGroup2);
            app.forelimbTab_2.Title = 'forelimb';
            app.flexionCheckBox = uicheckbox(app.forelimbTab_2);
            app.flexionCheckBox.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBoxValueChanged, true);
            app.flexionCheckBox.Text = 'flexion';
            app.extensionCheckBox = uicheckbox(app.forelimbTab_2);
            app.extensionCheckBox.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBoxValueChanged, true);
            app.extensionCheckBox.Text = 'extension';
            app.medialrotationCheckBox = uicheckbox(app.forelimbTab_2);
            app.medialrotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @medialrotationCheckBoxValueChanged, true);
            app.medialrotationCheckBox.Text = 'medial rotation';
            app.lateralrotationCheckBox = uicheckbox(app.forelimbTab_2);
            app.lateralrotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @lateralrotationCheckBoxValueChanged, true);
            app.lateralrotationCheckBox.Text = 'lateral rotation';
            app.protractionCheckBox_2 = uicheckbox(app.forelimbTab_2);
            app.protractionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @protractionCheckBox_2ValueChanged, true);
            app.protractionCheckBox_2.Text = 'protraction';
            app.retractionCheckBox_2 = uicheckbox(app.forelimbTab_2);
            app.retractionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @retractionCheckBox_2ValueChanged, true);
            app.retractionCheckBox_2.Text = 'retraction';
            app.abductionCheckBox = uicheckbox(app.forelimbTab_2);
            app.abductionCheckBox.ValueChangedFcn = createCallbackFcn(app, @abductionCheckBoxValueChanged, true);
            app.abductionCheckBox.Text = 'abduction';
            app.adductionCheckBox = uicheckbox(app.forelimbTab_2);
            app.adductionCheckBox.ValueChangedFcn = createCallbackFcn(app, @adductionCheckBoxValueChanged, true);
            app.adductionCheckBox.Text = 'adduction';
            app.elevationCheckBox = uicheckbox(app.forelimbTab_2);
            app.elevationCheckBox.ValueChangedFcn = createCallbackFcn(app, @elevationCheckBoxValueChanged, true);
            app.elevationCheckBox.Text = 'elevation';
            app.depressionCheckBox = uicheckbox(app.forelimbTab_2);
            app.depressionCheckBox.ValueChangedFcn = createCallbackFcn(app, @depressionCheckBoxValueChanged, true);
            app.depressionCheckBox.Text = 'depression';
            app.pronationCheckBox = uicheckbox(app.forelimbTab_2);
            app.pronationCheckBox.ValueChangedFcn = createCallbackFcn(app, @pronationCheckBoxValueChanged, true);
            app.pronationCheckBox.Text = 'pronation';
            app.supinationCheckBox = uicheckbox(app.forelimbTab_2);
            app.supinationCheckBox.ValueChangedFcn = createCallbackFcn(app, @supinationCheckBoxValueChanged, true);
            app.supinationCheckBox.Text = 'supination';
            app.trunkTab_2 = uitab(app.TabGroup2);
            app.trunkTab_2.Title = 'trunk';
            app.clockwiserotationCheckBox = uicheckbox(app.trunkTab_2);
            app.clockwiserotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @clockwiserotationCheckBoxValueChanged, true);
            app.clockwiserotationCheckBox.Text = 'clockwise rotation';
            app.counterclockwiserotationCheckBox = uicheckbox(app.trunkTab_2);
            app.counterclockwiserotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @counterclockwiserotationCheckBoxValueChanged, true);
            app.counterclockwiserotationCheckBox.Text = 'counter clockwise rotation';
            app.skinhairmovementCheckBox = uicheckbox(app.trunkTab_2);
            app.skinhairmovementCheckBox.ValueChangedFcn = createCallbackFcn(app, @skinhairmovementCheckBoxValueChanged, true);
            app.skinhairmovementCheckBox.Text = 'skin/ hair movement';
            app.flexionCheckBox_2 = uicheckbox(app.trunkTab_2);
            app.flexionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBox_2ValueChanged, true);
            app.flexionCheckBox_2.Text = 'flexion';
            app.extensionCheckBox_2 = uicheckbox(app.trunkTab_2);
            app.extensionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBox_2ValueChanged, true);
            app.extensionCheckBox_2.Text = 'extension';
            app.lateralflexiontoipsilateralsideCheckBox = uicheckbox(app.trunkTab_2);
            app.lateralflexiontoipsilateralsideCheckBox.ValueChangedFcn = createCallbackFcn(app, @lateralflexiontoipsilateralsideCheckBoxValueChanged, true);
            app.lateralflexiontoipsilateralsideCheckBox.Text = 'lateral flexion (to ipsilateral side)';
            app.lateralflexiontocontralateralsideCheckBox = uicheckbox(app.trunkTab_2);
            app.lateralflexiontocontralateralsideCheckBox.ValueChangedFcn = createCallbackFcn(app, @lateralflexiontocontralateralsideCheckBoxValueChanged, true);
            app.lateralflexiontocontralateralsideCheckBox.Text = 'lateral flexion (to contralateral side)';
            app.hindlimbTab = uitab(app.TabGroup2);
            app.hindlimbTab.Title = 'hindlimb';
            app.flexionCheckBox_3 = uicheckbox(app.hindlimbTab);
            app.flexionCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBox_3ValueChanged, true);
            app.flexionCheckBox_3.Text = 'flexion';
            app.extensionCheckBox_3 = uicheckbox(app.hindlimbTab);
            app.extensionCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBox_3ValueChanged, true);
            app.extensionCheckBox_3.Text = 'extension';
            app.medialrotationCheckBox_2 = uicheckbox(app.hindlimbTab);
            app.medialrotationCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @medialrotationCheckBox_2ValueChanged, true);
            app.medialrotationCheckBox_2.Text = 'medial rotation';
            app.lateralrotationCheckBox_2 = uicheckbox(app.hindlimbTab);
            app.lateralrotationCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @lateralrotationCheckBox_2ValueChanged, true);
            app.lateralrotationCheckBox_2.Text = 'lateral rotation';
            app.abductionCheckBox_2 = uicheckbox(app.hindlimbTab);
            app.abductionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @abductionCheckBox_2ValueChanged, true);
            app.abductionCheckBox_2.Text = 'abduction';
            app.adductionCheckBox_2 = uicheckbox(app.hindlimbTab);
            app.adductionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @adductionCheckBox_2ValueChanged, true);
            app.adductionCheckBox_2.Text = 'adduction';
            app.pronationCheckBox_2 = uicheckbox(app.hindlimbTab);
            app.pronationCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @pronationCheckBox_2ValueChanged, true);
            app.pronationCheckBox_2.Text = 'pronation';
            app.supinationCheckBox_2 = uicheckbox(app.hindlimbTab);
            app.supinationCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @supinationCheckBox_2ValueChanged, true);
            app.supinationCheckBox_2.Text = 'supination';
            app.inversionCheckBox = uicheckbox(app.hindlimbTab);
            app.inversionCheckBox.ValueChangedFcn = createCallbackFcn(app, @inversionCheckBoxValueChanged, true);
            app.inversionCheckBox.Text = 'inversion';
            app.eversionCheckBox = uicheckbox(app.hindlimbTab);
            app.eversionCheckBox.ValueChangedFcn = createCallbackFcn(app, @eversionCheckBoxValueChanged, true);
            app.eversionCheckBox.Text = 'eversion';
            app.tailTab_2 = uitab(app.TabGroup2);
            app.tailTab_2.Title = 'tail';
            app.checkanymovementcheckboxLabel = uilabel(app.tailTab_2);
            app.checkanymovementcheckboxLabel.Text = 'check any movement checkbox';
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.addonemoreitemButton = uibutton(app.UIFigure, 'push');
            app.addonemoreitemButton.ButtonPushedFcn = createCallbackFcn(app, @addonemoreitemButtonPushed, true);
            app.addonemoreitemButton.Text = 'add one more item';
            app.doneButton = uibutton(app.UIFigure, 'push');
            app.doneButton.ButtonPushedFcn = createCallbackFcn(app, @doneButtonPushed, true);
            app.doneButton.Text = 'done';
            app.anymovementCheckBox = uicheckbox(app.UIFigure);
            app.anymovementCheckBox.ValueChangedFcn = createCallbackFcn(app, @anymovementCheckBoxValueChanged, true);
            app.anymovementCheckBox.Text = 'any movement';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chooseMvt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end